classdef simulation_ui2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        SetsimulationTab              matlab.ui.container.Tab
        Panel                         matlab.ui.container.Panel
        AgentsEditFieldLabel          matlab.ui.control.Label
        AgentsEditField               matlab.ui.control.NumericEditField
        AttackersEditFieldLabel       matlab.ui.control.Label
        AttackersEditField            matlab.ui.control.NumericEditField
        StartsimulationButton         matlab.ui.control.Button
        DisplayrolesSwitchLabel       matlab.ui.control.Label
        DisplayrolesSwitch            matlab.ui.control.Switch
        ExpfilenameEditFieldLabel     matlab.ui.control.Label
        ExpfilenameEditField          matlab.ui.control.EditField
        LoadcsvButton                 matlab.ui.control.Button
        ParametersTab                 matlab.ui.container.Tab
        Panel_2                       matlab.ui.container.Panel
        SimulationruntimeEditFieldLabel  matlab.ui.control.Label
        SimulationruntimeEditField    matlab.ui.control.NumericEditField
        TimestepEditFieldLabel        matlab.ui.control.Label
        TimestepEditField             matlab.ui.control.NumericEditField
        AgentslinearspeedEditFieldLabel  matlab.ui.control.Label
        AgentslinearspeedEditField    matlab.ui.control.NumericEditField
        ProtectedzonelengthEditFieldLabel  matlab.ui.control.Label
        ProtectedzonelengthEditField  matlab.ui.control.NumericEditField
        FieldlengthEditFieldLabel     matlab.ui.control.Label
        FieldlengthEditField          matlab.ui.control.NumericEditField
        SightframesizeEditFieldLabel  matlab.ui.control.Label
        SightframesizeEditField       matlab.ui.control.NumericEditField
        NaiveCheckBox                 matlab.ui.control.CheckBox
        ExpTable                      matlab.ui.container.Tab
        Panel_3                       matlab.ui.container.Panel
        UITable                       matlab.ui.control.Table
    end

    
    properties (Access = private)
        RunExp = false; % determine if need to run experiments
        Env % multi-robot environment
        NumOfRob; % total # of robots
        NumOfAgents; % number of agents
        NumOfAtc;% number of attackers
        OcMap; % Occupancy map
        FieldLen = 100;% length of field side
        PZoneLen = 20;% Protected Zone side length
        pZoneMaxX; % Protected Zone max x position
        pZoneMinX; % Protected Zone min x position
        pZoneMaxY; % Protected Zone max y position
        pZoneMinY; % Protected Zone min y position
        Pzone_poly; % pzone polyshape
        sbZoneMinX; % standby min x position
        sbZoneMinY; % standby min y position
        sbZoneMaxX; % standby max x position
        sbZoneMaxY; % standby max y position
        sbDist = 0.25; % standby distance ratio
        Poses; % Position matrix for the robots
        FrameSize = 10;
        FrameInitAg = 0.2; % Frame ratio for initial agent positions
        LifeFlags; % Life markers for the robots
        SimTime = 60; % total simulation time (sec)
        SampleTime = 0.1; % time stemp (sec)
        TimeVec; % time vector
        ATC_EVADE_RAD = 6; % attackers evade radius
        ATC_TER_RAD = 2; % attackers terminated radius
        roles; % roles vector
        agentV = 1.5; % agents velocity
        ScatterSize = 20;
        DisplayVec; % vector of scatter objects for display
        NaiveFlag = false;
        
    end
    
    methods (Access = private)
        
         % check if the protected zone is covered
         function [covered,uncover_center]=is_pz_covered(app,poly_vec)
            % create one polyshape
            u_cell = union(poly_vec);
            uncover_center = [Inf Inf];
            % intersect the pzone and the cells
            inter = intersect(u_cell,app.Pzone_poly);
            covered = round(area(inter),3) == area(app.Pzone_poly);
            if(~covered)
                [cx,cy] = centroid(subtract(app.Pzone_poly,u_cell));
                uncover_center = [cx cy];
                if(isnan(cx)) % Check if there is no polygon
                    u_cell
                    app.Pzone_poly
                end
            end
            
        end
        
%         % check if the protected zone is covered
%         function covered=is_pz_covered(app,vertices,cells)
%             covered = false;
%             % set pzone coordinates
%             pzone_x = linespace(app.pZoneMinX,app.pZoneMaxX,app.PZoneLen)'; 
%             pzone_x = [pzone_x;pzone_x];
%             pzone_y = linespace(app.pZoneMinY,app.pZoneMaxY,app.PZoneLen)';
%             pzone_y = [pzone_y;pzone_y];
%             
%             % set fist cell coordinates
%             curr_cell = vertices(cells(1));
%             % check if pzone is covered by the first cell
%             [in,on] = inpolygon(pzone_x,pzone_y,curr_cell(:,1),curr_cell(:,2));
%             % set current cover status
%             curr_cover = in|on;
%             
%             % run on all the others cells
%             for i=2:length(cells)
%                 % set current cell coordinates
%                 curr_cell = vertices(cells(i));
%                 cx = curr_cell(:,1);
%                 cy = curr_cell(:,2);        
%                 % check if pzone is covered by current cell
%                 [in,on] = inpolygon(pzone_x,pzone_y,cx,cy);
%                 % set current cover status
%                 curr_cover = curr_cover|(in|on);
%                 % check if all pzone is fully covered
%                 if ~any(curr_cover(:) == 0)
%                     covered = true;
%                     break;
%                 end
%             end
%         end


        % return position for attacker
        function res = get_atacker_init_pos(app)
            pos1 = (rand(1,1)-0.5)*2; % get rand in [-1,1]
            negIdx = (pos1<0);
            posIdx = (pos1>=0);
            % map to [-10,0] or [100,110]
            pos1 = app.FrameSize .* pos1;
            pos1 = ((app.FieldLen/2)+ pos1).*posIdx ...
                + (-(app.FieldLen/2)+ pos1).*negIdx  ...
                + app.FieldLen/2;
            % map to [-10,110]
            pos2 = (-app.FrameSize+(app.FieldLen+(2*app.FrameSize)) .* rand(1,1));
            
            coinFlip = (rand(1,1)-0.5)*2;
            if coinFlip >= 0
                res = [pos1; pos2;pi*rand(1,1)];
            else
                res = [pos2; pos1;pi*rand(1,1)];
            end
        end


        % return position for defender
        function pos = get_agent_init_pos(app)
            offset = app.FrameInitAg * app.FieldLen;
            frameSize = offset*2+app.PZoneLen;
            pos = [(rand(2, 1))*frameSize+offset;pi*rand(1,1)];
        end
        
        % get relevent point of protected zone
        function target = get_point_of_pzone(app,point)
            curr_x = point(1);
            curr_y = point(2);
            if app.pZoneMinX <= curr_x && curr_x <= app.pZoneMaxX
                x = curr_x;
            elseif curr_x > app.pZoneMaxX
                x = app.pZoneMaxX;
            else
                x = app.pZoneMinX;
            end 
            
            if app.pZoneMinY <= curr_y && curr_y <= app.pZoneMaxY
                y = curr_y;
            elseif curr_x > app.pZoneMaxY
                y = app.pZoneMaxY;
            else
                y = app.pZoneMinY;
            end
            target = [x y];
        end
        
%         function idx = get_closest_point(app,points)
%             min_dis = Inf;
%             idx = 0;
%             for i=1:length(points)
%                 % get relevent point
%                 pZone = app.get_point_of_pzone(points(i,:));
%                 % calc distance
%                 curr_dis = norm(pZone - points(i));
%                 % update current
%                 if curr_dis < min_dis
%                     idx = i;
%                     min_dis = curr_dis;
%                 end
%             end
%         end
        
function [w,evade] = calc_atk_angular_speed(app,evade_radius,pos,robots,idx)
            w = 0;
            evade = false;
            temp_w = 0;
            heading = mod(pos(3),2*pi);
            [~,n] = size(robots);
            % run on all robots
            for i=1:n
                u = robots(:,i) - pos(1:2);
                v = [0;1];
                % calc distance
                distance = norm(u);
                if  distance <= evade_radius
                    % calc angle between the vectors
                    cos_theta = max(min(dot(u,v)/distance,1),-1);
                    theta = real(acosd(cos_theta));
                    r_pos = robots(:,i);
                    if r_pos(2) < pos(2)
                        theta = theta + pi;   
                    elseif r_pos(2) == pos(2)
                        if r_pos(1) < pos(1)
                            theta = theta + pi;
                        end
                    end
                    theta = mod(theta,2*pi);
                    diff = heading - theta;
                    if diff >= -pi/3 && diff <=  0
                       temp_w = interp1([0,pi/3],[0.5,2],pi/3+diff);
                       if  abs(temp_w) > abs(w)
                           w = temp_w;
                           evade = true;
                       end
                    elseif diff <= pi/3 && diff > 0
                       w = interp1([0,pi/3],[-2,-0.5],pi/3-diff);
                       evade = true;
                       break;
                    end
                end
            end
        end
        
        % attackers controler
        function vel = AtCtrl(app, rIdx)
            v = 0;
            w = 0;
            pos = app.Poses(:,rIdx);
            if(app.LifeFlags(rIdx))
                v = 1;
                defenders_pos = app.Poses(1:2,1:app.NumOfAgents);
                norms = vecnorm(defenders_pos - pos(1:2));
                is_below_rad = norms <= app.ATC_TER_RAD;
                
                % check if terminated
                if find(is_below_rad) 
                    % set the attacker as terminated
                    app.LifeFlags(rIdx) = 0;
                    v = 0;
                    w = 0;
                    % remove attacker from field
                    scatter(pos(1),pos(2),200,'X','black','LineWidth',2);
                end
                if(app.LifeFlags(rIdx))
                    [w,evade] = app.calc_atk_angular_speed(app.ATC_EVADE_RAD,pos,defenders_pos,rIdx);
                    if ~evade
                        % move toward protected zone
                        % get point to move to
                        target = app.get_point_of_pzone(pos);
                        w = utils.calc_angular_speed_by_point(pos,target); 
                    end
                end
            end
            vel = bodyToWorld([v;0;w],pos);
        end
        
        % Determine role
        function role=determine_role(app,cell)
            inter = intersect(cell,app.Pzone_poly);
            role = int8(area(inter) ~= 0);%TODO: add error tolerance +-5%
        end
        
        % calc vel for active keeper and active chaser
        function [v,w]=get_vel_active_policy(app,rIdx,cells,closest_att)
            v = 0;
            w = 0;
            pose = app.Poses(:,rIdx); 
            % calc angular speed
            % find edge middle
            inter = intersect(cells{rIdx}',cells{closest_att}','rows');
            ver1 = inter(1,:)';
            ver2 = inter(2,:)';
            target = (ver1 + ver2)./2;
            w = utils.calc_angular_speed_by_point(pose,target);
            v = app.agentV;
        end
        
        % naive policy
        function vel= NaivePolicy(app,rIdx,neighbors,cells,reduce_poses)
            v = 0;
            w = 0;
            pose = reduce_poses(:,rIdx);
            cl_to_neigh = find(neighbors(rIdx,:));
            if(numel(cl_to_neigh) > 0)
                closest_att=cl_to_neigh(1);
                att_pose = reduce_poses(:,closest_att);
                w = utils.calc_angular_speed_by_point(pose,att_pose);
            v = app.agentV;
            end
            vel = bodyToWorld([v;0;w],pose);
        end
        
        % Agents keepers controler   
        function vel = AgKeepCtrl(app, rIdx,is_cover,uncover_center,neighbors,cells,reduce_poses)
            v = 0;
            w = 0;
            pose = reduce_poses(:,rIdx);          
            % Find closest attacker, which the agent is closest to.
            cl_to_neigh = find(neighbors(rIdx,:));
            ag_pos = pose(1:2);
            min_dist = Inf;
            closest_att=-1;
            for i=1:numel(cl_to_neigh)
                cl_to_att = find(neighbors(cl_to_neigh(i),:));
                is_neigh = cl_to_att == rIdx;
                neigh_idx = find(is_neigh);
                if(numel(neigh_idx) > 0)
                    att_pos = reduce_poses(1:2, cl_to_neigh(i));
                    dist = norm(att_pos-ag_pos);
                    if dist < min_dist
                        min_dist =dist;
                        closest_att = cl_to_neigh(i);
                    end
                end
            end
            if closest_att == -1
                % ******* Passive defence policy *********
                % check if p zone is covered
                if(~is_cover)
                    % move to mass center
                    w = utils.calc_angular_speed_by_point(pose,uncover_center);
                    if(isnan(w)) %TODO: delete later
                        rIdx
                        pose 
                        v
                        w
                    end
                    v= app.agentV;
                end
                % check if display roles is on
                if(app.DisplayrolesSwitch.Value ~= "Off")
                    hold on
                    app.DisplayVec(rIdx) = scatter(ag_pos(1),ag_pos(2),app.ScatterSize,'c',"filled");
                end
            else 
                % ******* Active defence policy ********
                [v,w] = app.get_vel_active_policy(rIdx,cells,closest_att);
                if(isnan(w))%TODO: delete later
                    rIdx
                    pose 
                    v
                    w
                end
                % check if display roles is on
                if(app.DisplayrolesSwitch.Value ~= "Off")
                    hold on
                     app.DisplayVec(rIdx) = scatter(ag_pos(1),ag_pos(2),app.ScatterSize,'m',"filled");
                end
            end
            vel = bodyToWorld([v;0;w],pose);
        end
        
        % Agents chasers controler
        function vel = AgChaseCtrl(app, rIdx,neighbors,cells,reduce_poses)
            v = 0;
            w = 0;
            pose = reduce_poses(:,rIdx);
            % Find closest attacker
            cl_to_neigh = find(neighbors(rIdx,:));
            ag_pos = pose(1:2);
            min_dist = Inf;
            closest_att=-1;
            if(numel(cl_to_neigh))
                att_pos = reduce_poses(1:2, cl_to_neigh(:));
                dist = vecnorm(att_pos-ag_pos);
                [min_dist,idx] = min(dist);
                closest_att = cl_to_neigh(idx);
            end
            % ******* Active-Chase policy ********
            if(closest_att ~= -1)
                [v,w] = app.get_vel_active_policy(rIdx,cells,closest_att);
                % check if display roles is on
                if(app.DisplayrolesSwitch.Value ~= "Off")
                    hold on
                     app.DisplayVec(rIdx) = scatter(ag_pos(1),ag_pos(2),app.ScatterSize,'yellow',"filled");
                end
            else
                % ******* Reserve policy ********
                % get all keepers neighbors
                k = find(app.roles(:));
                keeprs_neighbors = neighbors(k,:);
                closest_att = -1;
                % find min distance to neighbor
                if(k)
                    [~,attackers] = find(keeprs_neighbors(:,:));
                    dist = vecnorm(ag_pos - reduce_poses(1:2,attackers));
                    [~,idx] = min(dist);
                    closest_att = attackers(idx);
                end
%                 for i=1:length(k)
%                     attackers = find(keeprs_neighbors(i,:));
%                     for j=1:length(attackers)
%                         curr_att = attackers(j);
%                         curr_dist = norm(ag_pos - reduce_poses(1:2,curr_att));
%                         if(curr_dist < min_dist)
%                             min_dist = curr_dist;
%                             closest_att = curr_att;
%                         end
%                     end   
%                 end
                if(closest_att ~= -1)
                    w = utils.calc_angular_speed_by_point(pose,reduce_poses(1:2,closest_att));
                    v = app.agentV;
                    % check if display roles is on
                    if(app.DisplayrolesSwitch.Value ~= "Off")
                        hold on
                        app.DisplayVec(rIdx) =  scatter(ag_pos(1),ag_pos(2),app.ScatterSize,'green',"filled");
                    end
                else 
                    % ******* Stand-by policy ********
                    target = ag_pos;
                    % inside standby zone
                    if((ag_pos(1) <= app.sbZoneMaxX && ag_pos(1) >= app.sbZoneMinX) && ...
                            (ag_pos(2) <= app.sbZoneMaxY && ag_pos(2) >= app.sbZoneMinY))
                        v = 0;
                        w = 0;
                    else 
                        % check to which point on the standby zone to move to
                        if((ag_pos(1) < app.sbZoneMinX))
                            target(1) = app.sbZoneMinX;
                        elseif((ag_pos(1) > app.sbZoneMaxX))
                            target(1) = app.sbZoneMaxX;
                        end
                        
                        if((ag_pos(2) < app.sbZoneMinY))
                            target(2) = app.sbZoneMinY;
                        elseif((ag_pos(2) > app.sbZoneMaxY))
                            target(2) = app.sbZoneMaxY;
                        end    
                        
                        v = app.agentV;
                        w = utils.calc_angular_speed_by_point(pose,target);
                    end
                    % check if display roles is on
                    if(app.DisplayrolesSwitch.Value ~= "Off")
                        hold on
                        app.DisplayVec(rIdx) =  scatter(ag_pos(1),ag_pos(2),app.ScatterSize,'black',"filled");
                    end
                end
            end
            vel = bodyToWorld([v;0;w],pose);
        end
  
        function poly_vec=create_polyVec(app,cells,numAgs)
            poly_vec = polyshape.empty(numAgs,0);
            % create polyshape
            for i=1:numAgs
                 % get current cell coordinates
                curr_cell = cells{i};
                cx = curr_cell(1,:);
                cy = curr_cell(2,:);   
                % add cell to polyshape vector
                curr_poly = polyshape(cx,cy);
                poly_vec(i) = curr_poly;
                app.roles(i) = app.determine_role(curr_poly);
            end
        end
        
        % robots controler
        function vel = robCtrl(app,vel)
%             % crreate lookup table for the robots
%             look_up = zeros(app.NumOfRob);
%             curr_idx = 1;
%             for i=1:app.NumOfRob
%                 if(app.LifeFlags(i))
%                   look_up(i) = curr_idx;
%                   curr_idx = curr_idx + 1;
%                 end
%             end
            % poses of living robots
            reduce_poses = app.Poses(:,find(app.LifeFlags));
            % get voronoi cells
            lBound = [-app.FrameSize -app.FrameSize];
            hBound = [app.FieldLen+app.FrameSize app.FieldLen+app.FrameSize];
            cells = utils.get_voronoi(reduce_poses(1:2,:),lBound,hBound);
            
            % create polyshapes for each voronoi cell and assign role for each agent
            %poly_vec = app.create_polyVec(cells,app.NumOfAgents);
            %poly_vec = polyshape.empty(app.NumOfAgents,0);
            poly_vec = arrayfun(@(cell) polyshape(cell{1}(1,:),cell{1}(2,:)),cells(1:app.NumOfAgents))';
            app.roles = arrayfun(@(poly)app.determine_role(poly),poly_vec);            
            
            % get neighbors of each robot
            neighbors = utils.get_neighbors(app.NumOfAgents,reduce_poses(1:2,:),cells);  
            
            % check if pzone is coverd by friendly voronoi cells
            [is_cover,uncover_center] = app.is_pz_covered(poly_vec);
            
            % run controllers
            indexes = 1:app.NumOfRob;
            if(app.NaiveFlag)
                vel(:,indexes(app.roles >= 0)) = cell2mat(arrayfun(@(idx)app.NaivePolicy(idx,neighbors,cells,reduce_poses),indexes(app.roles >= 0),'UniformOutput',false));
            else
                % Keeper controller vel(:,indexes(app.roles == 1))
                vel(:,indexes(app.roles == 1)) = cell2mat(arrayfun(@(idx)app.AgKeepCtrl(idx,is_cover,uncover_center,neighbors,cells,reduce_poses),indexes(app.roles == 1),'UniformOutput',false));
                % Chaser controller
                vel(:,indexes(app.roles == 0)) = cell2mat(arrayfun(@(idx)app.AgChaseCtrl(idx,neighbors,cells,reduce_poses),indexes(app.roles == 0),'UniformOutput',false));
            end
            % attacker controller
            vel(:,app.NumOfAgents + 1:end) = cell2mat(arrayfun(@(idx)app.AtCtrl(idx),indexes(app.NumOfAgents + 1:end),'UniformOutput',false));
            
            % run controllers
%             for i=1:app.NumOfRob
%                 if(app.LifeFlags(i))
%                     if i <= app.NumOfAgents
%                         if app.roles(i)
%                            % Keeper controller
%                            vel(:,i) = app.AgKeepCtrl(i,is_cover,uncover_center,neighbors,cells,reduce_poses);
%                         else
%                            % Chaser controller
%                            vel(:,i) = app.AgChaseCtrl(i,neighbors,cells,reduce_poses);
%                         end
%                     else
%                        vel(:,i) = app.AtCtrl(i);
%                     end
%                 end
%             end
        end
        
        % check if there is an attacker in the protected zone
        function is_breached=check_if_defense_breached(app)
            is_breached = false;
            % run on all attackers
            for i=app.NumOfAgents + 1:app.NumOfRob
                % add 1 for the robot radius
                pos = app.Poses(1:2,i);
                % closest on the pzone to the attacker
                pZone_point = pos;
                % find point on pzone
                if((pos(1) < app.pZoneMinX))
                    pZone_point(1) = app.pZoneMinX;
                elseif((pos(1) > app.pZoneMaxX))
                    pZone_point(1) = app.pZoneMaxX;
                end
                
                if((pos(2) < app.pZoneMinY))
                    pZone_point(2) = app.pZoneMinY;
                elseif((pos(2) > app.pZoneMaxY))
                    pZone_point(2) = app.pZoneMaxY;
                end    
                
                % calc the distance
                dist = norm(pos - pZone_point);
                if(dist - app.Env.robotRadius(1) <= 0)
                    is_breached = true;
                    break;
                end
            end
        end
        
        function change_displayVec(app,curr)
            if(~isa(curr,'matlab.graphics.GraphicsPlaceholder'))
                set(curr,'Visible','off')
            end
        end
        
        % run the simulation loop
        function [win,defense_time]=simulation_loop(app)
            win = -1;
            vel=zeros(3, app.NumOfRob);
            % run on each timestamp
           % vor = voronoi(app.Poses(1,:)',app.Poses(2,:)');
            % set empty roles
            app.roles = zeros(1,app.NumOfAgents);
            % set scatter display vector
            app.DisplayVec = gobjects(1,app.NumOfAgents);
            for currStemp =2:numel(app.TimeVec)
                % update env
                app.Env(1:app.NumOfRob, app.Poses);
                xlim([0 app.FieldLen]);   % Without this, axis resizing can slow things down
                ylim([0 app.FieldLen]); 
                
                arrayfun(@(x) app.change_displayVec(x),app.DisplayVec);

                % check if there is an attacker in the protected zone
                if (app.check_if_defense_breached())
                    win = 0;
                     % display win message
                     icon = imresize(imread('robot_devil_lost.jpg'),[100 100]);
                     %waitfor(msgbox('Agents failed to protected the zone','Failure','custom',icon));
                     defense_time = app.TimeVec(currStemp);
                    break;
                % cheack if all the attackers are dead
                elseif(length(find(app.LifeFlags)) == app.NumOfAgents)
                    win = 1;
                    % display win message
                    icon = imresize(imread('bender_success.png'),[100 100]);
                    %waitfor(msgbox('Agents successfully protected the zone','Success','custom',icon));
                    defense_time = app.TimeVec(currStemp);
                    break;
                end
                
                % display voronoi
                % set display off only if vor exist
                if(exist('vor',"var"))
                    set(vor,'Visible','off')
                end
                % poses of living robots
                reduce_poses = app.Poses(1:2,find(app.LifeFlags));
                hold on
                vor = voronoi(reduce_poses(1,:)',reduce_poses(2,:)');
                set(vor,'Visible','on')
                % run robot controller
                vel = app.robCtrl(vel);
                
                % Calculate next timestamp poses
                app.Poses = app.Poses + vel*app.SampleTime;
            end
            if(win == -1)
                win = 2;
                defense_time = app.SimTime;
            end
        end
        
        function set_parameters(app,num_of_ag,num_of_att,agent_speed,field_length,pzone_length,sim_time)
            % set number of agents
            if(isnan(num_of_ag))
                app.NumOfAgents = app.AgentsEditField.Value;
            else
                app.NumOfAgents = num_of_ag;
            end
            
            % set number of attackers
            if(isnan(num_of_att))
                app.NumOfAtc = app.AttackersEditField.Value;
            else
                app.NumOfAtc = num_of_att;
            end
            app.NumOfRob = app.NumOfAgents + app.NumOfAtc;
            
            % set agents speed
            if(isnan(agent_speed))
                app.agentV = app.AgentslinearspeedEditField.Value;
            else
                app.agentV = agent_speed;
            end
            
            % set field length
            if(isnan(field_length))
                app.FieldLen = app.FieldlengthEditField.Value;
            else
                app.FieldLen = field_length;
            end
            
            % set pzone length
            if(isnan(pzone_length))
                app.PZoneLen = app.ProtectedzonelengthEditField.Value;
            else
                app.PZoneLen = pzone_length;
            end
            
             % set simulation time
            if(isnan(sim_time))
                app.SimTime = app.SimulationruntimeEditField.Value;
            else
                app.SimTime = sim_time;
            end

            app.SampleTime = app.TimestepEditField.Value;
            app.FrameSize = app.SightframesizeEditField.Value;
            
            % create occupancy map
            app.OcMap = binaryOccupancyMap(app.FieldLen, app.FieldLen);
            assignin("base","map", app.OcMap)
            fieldMid = fix(app.FieldLen/2);
            pzMid = fix (app.PZoneLen/2);
            x = fieldMid-pzMid+1:fieldMid+pzMid;
            y = fieldMid-pzMid+1:fieldMid+pzMid;
            [p,q] = meshgrid(x,y);
            xy = [p(:) q(:)];
            setOccupancy(app.OcMap, xy, ones((app.PZoneLen)^2,1))
            
            % set protected zone
            fieldMid = fix(app.FieldLen/2);
            pzMid = fix (app.PZoneLen/2);
            app.pZoneMaxX = fieldMid+pzMid;
            app.pZoneMinX = fieldMid-pzMid;
            app.pZoneMaxY = fieldMid+pzMid;
            app.pZoneMinY = fieldMid-pzMid;
            pzone_x = [app.pZoneMinX;app.pZoneMinX;app.pZoneMaxX;app.pZoneMaxX];
            pzone_y = [app.pZoneMinY;app.pZoneMaxY;app.pZoneMaxY;app.pZoneMinY];
            app.Pzone_poly = polyshape(pzone_x,pzone_y);
            
            % set stand-by zone
            app.sbZoneMinX = app.pZoneMinX*(1-app.sbDist);
            app.sbZoneMaxX = app.FieldLen*app.sbDist + app.pZoneMaxX*(1-app.sbDist);
            app.sbZoneMinY = app.pZoneMinY*(1-app.sbDist);
            app.sbZoneMaxY = app.FieldLen*app.sbDist + app.pZoneMaxY*(1-app.sbDist);             
        end
        
        function prepare_data_for_simulation(app)
            % init multi-robot environment
            app.Env = MultiRobotEnv(app.NumOfRob);
            app.Env.robotRadius = 1 ;
            app.Env.showTrajectory = false;
            app.Env.mapName = "map";
            
            % divide robots to groups
            a = repmat([0 0 1],[app.NumOfAgents 1]);
            b = repmat([1 0 0],[app.NumOfAtc 1]);
            app.Env.robotColors = [a;b];
            % Initialize poses
            app.Poses= [];
            for j=1:app.NumOfRob
                if j<= app.NumOfAgents
                    pos = app.get_agent_init_pos();
                    app.Poses=[app.Poses pos];
                else
                    pos = app.get_atacker_init_pos();
                    app.Poses=[app.Poses pos];
                end
            end
        
            % set time vector
            app.TimeVec = 0:app.SampleTime:app.SimTime;
            % initialize life markers
            app.LifeFlags = ones(app.NumOfRob,1);    
        end        
        
        % run an experiment
        function [res,defense_time] = run_experiment(app,row)
            % set parameters
            num_agents = row(1);
            num_att = row(2);
            speed = row(3);
            filed_len = row(4);
            pzone_len = row(5);
            sim_time = row(6);
            iter = row(7);
            app.set_parameters(num_agents,num_att,speed,filed_len,pzone_len,sim_time); 
            res = zeros(1,iter);
            defense_time = zeros(1,iter);
            % run for n iterations
            for i=1:iter
                try
                    app.prepare_data_for_simulation();
                    [res(i),defense_time(i)] = app.simulation_loop();
                catch exception
                    'somthing went wrong again...'
                    writematrix(app.Poses,'poses_exeption.csv') 
                    % print exception
                    exception
                    % run iteration again
                    app.prepare_data_for_simulation();
                    [res(i),defense_time(i)] = app.simulation_loop();
                end
            end
        end
        
        function row = create_data_row(app,res,param,d_time,bundle)
            row = zeros(param(7),10);
            row(:,1) = bundle;
            row(:,2) = 1:param(7);
            row(:,3:end-2) = ones(param(7),6).*param(:,1:end-1);
            row(:,end -1) = d_time;
            row(:,end) = res;
            row
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UITable.Data = zeros(1,numel(app.UITable.ColumnName));
            % set default parameters 
            app.set_parameters(nan,nan,nan,nan,nan,nan);
        end

        % Button pushed function: StartsimulationButton
        function StartsimulationButtonPushed(app, event)
            % check if need to run experiment
            if(app.RunExp)
                % create table for experiment data
                %result = 0=lost,1 - win kil, 2 - win time
                headers = {'#_Bundle','#_Iteration','#_Agents','#_Attackers','Linar_Speed','Field_Len','Pzone_Len','Sim_Time','defense_time','Result'};
                data = [];
                % run simulation for each row in the experiments table
                for i=1:size(app.UITable.Data,1) -1
                    params = app.UITable.Data(i,:);
                    [res,defense_time]= app.run_experiment(params);
                    data = [data;app.create_data_row(res,params,defense_time,i)];
                    % save progress
                    % create table of data
                    T = array2table(data,'VariableNames',headers);
                    % save to file
                    writetable(T,app.ExpfilenameEditField.Value);
                end
            else
                % run single simulation
                app.set_parameters(nan,nan,nan,nan,nan,nan);
                app.prepare_data_for_simulation();
                app.simulation_loop();
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % close all figures
            close all
            % clear all variables
            evalin('base','clear all')
            % delete app
            delete(app);
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.RunExp = true; % TODO: might need to change through button
            % check if the last line is zeroes
            if(numel(find(app.UITable.Data(end,:))) > 0)
                % add new line
                app.UITable.Data = [app.UITable.Data;zeros(1,size(app.UITable.Data,2))];
            end
            % check for rows zeroes
            zero_row_idx = find(all(app.UITable.Data(1:end -1,:) == 0,2));
            if(numel(zero_row_idx) >0)
                % remove rows of zeroes
                app.UITable.Data(zero_row_idx,:) = [];
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            % check if table tab was selected
            if(strcmp(selectedTab.Title,'Exp Table'))
                w = 560;
            else
                w = 350;
            end
            % set size
            x = app.UIFigure.Position(1);
            y = app.UIFigure.Position(2);
            app.UIFigure.Position = [x y w 245];
            app.TabGroup.Position = [1 1 w 245];
        end

        % Button pushed function: LoadcsvButton
        function LoadcsvButtonPushed(app, event)
            % display dialog
            [file_name,path] = uigetfile();
            file = strcat(path,file_name);
            % load table 
            T = readtable(file);
            app.UITable.Data = table2array(T);
            app.RunExp = true;
        end

        % Value changed function: NaiveCheckBox
        function NaiveCheckBoxValueChanged(app, event)
            app.NaiveFlag = app.NaiveCheckBox.Value; 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.SetsimulationTab = uitab(app.TabGroup);
            app.SetsimulationTab.Title = 'Set simulation';
            app.Panel = uipanel(app.SetsimulationTab);
            app.AgentsEditFieldLabel = uilabel(app.Panel);
            app.AgentsEditFieldLabel.Text = 'Agents';
            app.AgentsEditField = uieditfield(app.Panel, 'numeric');
            app.AgentsEditField.Limits = [1 Inf];
            app.AgentsEditField.RoundFractionalValues = 'on';
            app.AgentsEditField.Value = 2;
            app.AttackersEditFieldLabel = uilabel(app.Panel);
            app.AttackersEditFieldLabel.Text = 'Attackers';
            app.AttackersEditField = uieditfield(app.Panel, 'numeric');
            app.AttackersEditField.Limits = [0 Inf];
            app.AttackersEditField.RoundFractionalValues = 'on';
            app.AttackersEditField.Value = 1;
            app.StartsimulationButton = uibutton(app.Panel, 'push');
            app.StartsimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StartsimulationButtonPushed, true);
            app.StartsimulationButton.Text = 'Start simulation';
            app.DisplayrolesSwitchLabel = uilabel(app.Panel);
            app.DisplayrolesSwitchLabel.Text = 'Display roles';
            app.DisplayrolesSwitch = uiswitch(app.Panel, 'slider');
            app.ExpfilenameEditFieldLabel = uilabel(app.Panel);
            app.ExpfilenameEditFieldLabel.Text = 'Exp file name';
            app.ExpfilenameEditField = uieditfield(app.Panel, 'text');
            app.LoadcsvButton = uibutton(app.Panel, 'push');
            app.LoadcsvButton.ButtonPushedFcn = createCallbackFcn(app, @LoadcsvButtonPushed, true);
            app.LoadcsvButton.Text = 'Load csv';
            app.ParametersTab = uitab(app.TabGroup);
            app.ParametersTab.Title = 'Parameters';
            app.Panel_2 = uipanel(app.ParametersTab);
            app.SimulationruntimeEditFieldLabel = uilabel(app.Panel_2);
            app.SimulationruntimeEditFieldLabel.Text = 'Simulation run time';
            app.SimulationruntimeEditField = uieditfield(app.Panel_2, 'numeric');
            app.SimulationruntimeEditField.Value = 60;
            app.TimestepEditFieldLabel = uilabel(app.Panel_2);
            app.TimestepEditFieldLabel.Text = 'Time step';
            app.TimestepEditField = uieditfield(app.Panel_2, 'numeric');
            app.TimestepEditField.Value = 0.1;
            app.AgentslinearspeedEditFieldLabel = uilabel(app.Panel_2);
            app.AgentslinearspeedEditFieldLabel.Text = 'Agents linear speed';
            app.AgentslinearspeedEditField = uieditfield(app.Panel_2, 'numeric');
            app.AgentslinearspeedEditField.Value = 1;
            app.ProtectedzonelengthEditFieldLabel = uilabel(app.Panel_2);
            app.ProtectedzonelengthEditFieldLabel.Text = 'Protected zone length';
            app.ProtectedzonelengthEditField = uieditfield(app.Panel_2, 'numeric');
            app.ProtectedzonelengthEditField.Value = 20;
            app.FieldlengthEditFieldLabel = uilabel(app.Panel_2);
            app.FieldlengthEditFieldLabel.Text = 'Field length';
            app.FieldlengthEditField = uieditfield(app.Panel_2, 'numeric');
            app.FieldlengthEditField.Value = 100;
            app.SightframesizeEditFieldLabel = uilabel(app.Panel_2);
            app.SightframesizeEditFieldLabel.Text = 'Sight frame size';
            app.SightframesizeEditField = uieditfield(app.Panel_2, 'numeric');
            app.SightframesizeEditField.Value = 10;
            app.NaiveCheckBox = uicheckbox(app.Panel_2);
            app.NaiveCheckBox.ValueChangedFcn = createCallbackFcn(app, @NaiveCheckBoxValueChanged, true);
            app.NaiveCheckBox.Text = 'Naive';
            app.ExpTable = uitab(app.TabGroup);
            app.ExpTable.Title = 'Exp Table';
            app.Panel_3 = uipanel(app.ExpTable);
            app.UITable = uitable(app.Panel_3);
            app.UITable.ColumnName = {'#_Agents'; '#_Attackers'; 'Linar_Speed'; 'Field_Len'; 'Pzone_Len'; 'Sim_Time'; '#_Iterations'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = simulation_ui2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end